classdef speed1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        COMMENTDESCRIPTIONSPanel      matlab.ui.container.Panel
        TextArea                      matlab.ui.control.TextArea
        Panel_11                      matlab.ui.container.Panel
        SPEEDOFTHECONTROLLEDCARLabel  matlab.ui.control.Label
        Gauge                         matlab.ui.control.SemicircularGauge
        Panel_10                      matlab.ui.container.Panel
        Slider                        matlab.ui.control.Slider
        SIMULATIONTIMELabel           matlab.ui.control.Label
        Panel_9                       matlab.ui.container.Panel
        VrefKnob                      matlab.ui.control.Knob
        REFERENCESPEEDLabel           matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        Panel_7                       matlab.ui.container.Panel
        Lamp                          matlab.ui.control.Lamp
        StatusLampLabel               matlab.ui.control.Label
        AUTONOMOUSVEHICLESPEEDCONTROLLERLabel  matlab.ui.control.Label
        Panel_5                       matlab.ui.container.Panel
        SpeedAxes                     matlab.ui.control.UIAxes
        Panel_4                       matlab.ui.container.Panel
        VEHICLEANIMATIONLabel         matlab.ui.control.Label
        VehicleAxes                   matlab.ui.control.UIAxes
        Panel_2                       matlab.ui.container.Panel
        KdLabel                       matlab.ui.control.Label
        KiLabel                       matlab.ui.control.Label
        KpLabel                       matlab.ui.control.Label
        Panel_8                       matlab.ui.container.Panel
        PIDCONTROLLERGAINSLabel       matlab.ui.control.Label
        dEditField                    matlab.ui.control.NumericEditField
        KiEditField                   matlab.ui.control.NumericEditField
        KpEditField                   matlab.ui.control.NumericEditField
        kpSlider                      matlab.ui.control.Slider
        kdSlider                      matlab.ui.control.Slider
        KiSlider                      matlab.ui.control.Slider
        Panel                         matlab.ui.container.Panel
        SSEField                      matlab.ui.control.NumericEditField
        SSEEditFieldLabel             matlab.ui.control.Label
        OvershootField                matlab.ui.control.NumericEditField
        OvershootEditFieldLabel       matlab.ui.control.Label
        SettlingTimeField             matlab.ui.control.NumericEditField
        SettlingTimeEditFieldLabel    matlab.ui.control.Label
        RiseTimeField                 matlab.ui.control.NumericEditField
        RiseTimeEditFieldLabel        matlab.ui.control.Label
        StartButton                   matlab.ui.control.StateButton
    end

    
    properties (Access = private)
        isRunning logical = false
        simTimer   % timer object
        t_span     % time vector
        y_out      % simulated output
        r_out      % reference signal

    end
    
    methods (Access = private)
        
        function runSimulation(app)
            if ~app.isRunning, return; end
            app.Slider.Value = 0;
            % Get user inputs
            Vref = app.VrefKnob.Value;        % reference speed
            Kp   = app.kpSlider.Value;
            Ki   = app.KiSlider.Value;
            Kd   = app.kdSlider.Value;
        
            % Plant parameters (FOPTD: K=5, tau=2, Td=0.3)
            K_plant = 5; tau = 2.0; Td = 0.3;
            dt = 0.05;  % time step (s)
            t  = 0:dt:10;  % 0 to 15 seconds
            n  = length(t);
        
            % Plant as first-order system using Euler integration
            % G(s) = K/(tau*s+1) approximated: dy/dt = (-y + K*u) / tau
            % Time delay handled by delay buffer
        
            delay_steps = round(Td / dt);
            u_buffer = zeros(1, delay_steps + 1);  % stores past inputs
        
            y = zeros(1, n);    % output (speed)
            u = zeros(1, n);    % control signal
            e_int = 0;          % integral error
            e_prev = 0;         % previous error
        
            for i = 2:n
                % Reference
                r = Vref;  % step input
        
                % PID error
                e = r - y(i-1);
                e_int = e_int + e * dt;
                e_der = (e - e_prev) / dt;
                e_prev = e;
        
                % PID output (clamp to [0, 1] = throttle)
                u_raw = Kp*e + Ki*e_int + Kd*e_der;
                u(i) = max(0, min(10, u_raw));  % saturation
        
                % Time delay: use delayed input
                u_buffer = [u(i), u_buffer(1:end-1)];
                u_delayed = u_buffer(end);
        
                % Plant: Euler integration of dy/dt = (-y + K*u_delayed)/tau
                dydt = (-y(i-1) + K_plant * u_delayed) / tau;
                y(i) = y(i-1) + dydt * dt;
            end
        
            % Store results
            app.t_span = t;
            app.y_out  = y;
            app.r_out  = Vref * ones(1, n);
        
            % Update all panels
            %updateResponsePlot(app);
            updateVehicleAnimation(app);
            updateMetrics(app);

            % Update speed gauge to final speed
            app.Gauge.Value = app.y_out(end);
        end
        
        function updateResponsePlot(app, k)
            % If no index given, plot everything (called at end of simulation)
            if nargin < 2
                k = length(app.t_span);
            end
        
            ax = app.SpeedAxes;
            cla(ax);
            hold(ax,'on');
        
            % Full reference line (always fully visible as a guide)
            plot(ax, app.t_span, app.r_out, 'r--', 'LineWidth', 1.2);
        
            % Output — only draw up to current frame k
            plot(ax, app.t_span(1:k), app.y_out(1:k), 'b-', 'LineWidth', 2);
        
            hold(ax,'off');
            xlabel(ax,'Time (s)');
            ylabel(ax,'Speed (m/s)');
            title(ax,'Longitudinal Speed Response');
            legend(ax,'Reference','Output','Location','southeast');
            grid(ax,'on');
            ylim(ax,[0, max(app.r_out(1)*1.6, 1)]);
            xlim(ax,[app.t_span(1), app.t_span(end)]);
        end
        
        function updateVehicleAnimation(app)
            ax = app.VehicleAxes;

            %% ── Pre-compute ──────────────────────────────────────────────────────
            dt       = app.t_span(2) - app.t_span(1);
            dist_cum = cumsum(app.y_out * dt);
            T_SIM    = app.t_span(end);     % = 15s — animation will last exactly this
        
            %% ── Road layout ──────────────────────────────────────────────────────
            ROAD_LEN = 100;
            RH       = 2.0;
        
            SPD_MAX  = app.VrefKnob.Limits(2);
            REF_DIST = SPD_MAX * T_SIM;    % fixed denominator — always 30 × 15 = 450m
        
            %% ── Car geometry ─────────────────────────────────────────────────────
           % CL  = 2.2;  CBW = 0.55;  BZ0 = 0.15;  BZ1 = 0.65;  CZ1 = 1.15;
            %CBf = 0.70; CBr = 0.90;  CCW = 0.45;
            %WR  = 0.28; WHW = 0.12;
            %WYC = CBW + WHW + 0.03;
            %WAxF = 0.90;  WAxR = -0.90;

            %% ── Car geometry — proper sedan proportions ──────────────────────
            % Overall body
            BL  = 3.8;    % body half-length (front bumper to rear bumper)
            BW  = 0.48;   % body half-width
            BZ0 = 0.30;   % body base Z (sits above ground = wheel radius)
            BZ1 = 0.70;   % shoulder line Z (top of lower body)
    
            % Cabin
            CBf = 0.85;   % cabin front X offset from car centre (shorter — leaves hood)
            CBr = 1;   % cabin rear X offset (leaves boot)
            CW  = 0.40;   % cabin half-width (slightly narrower than body)
            CZ1 = 1.00;   % roof Z
    
            % Wheels
            WR  = 0.32;   % wheel radius
            WHW = 0.13;   % wheel half-width
            WYC = BW + WHW + 0.02;
            WAxF =  2.20;  % front axle X
            WAxR = -1.50;  % rear axle X
    
            %% ── Colours ──────────────────────────────────────────────────────
            bodyC  = [0.10 0.32 0.82];   % car blue
            cabC   = [0.07 0.22 0.60];   % darker blue cabin
            glassC = [0.50 0.75 0.92];   % light blue glass
            tyrC   = [0.08 0.08 0.08];   % near black tyre
            rimC   = [0.75 0.75 0.78];   % silver rim
            hlC    = [1.00 1.00 0.82];   % warm white headlights
            tlC    = [0.92 0.04 0.04];   % red taillights
            undC   = [0.06 0.06 0.06];   % undercarriage

            %% ── Wheel template ───────────────────────────────────────────
            nth = 8;
            ang = linspace(0, 2*pi, nth+1);
            CT  = cos(ang);  ST = sin(ang);
            F6  = [1 2 3 4; 5 6 7 8; 1 2 6 5; 2 3 7 6; 3 4 8 7; 4 1 5 8];
            WX_all = [WAxF,  WAxF,  WAxR,  WAxR];
            WY_all = [ WYC, -WYC,   WYC,  -WYC];
            dashStarts = 5 : 8 : ROAD_LEN;   % ← ADD HERE
                
%% ══ Animation loop / for k = ...  ═══════════════════════════
        
            %% ══ Real-time animation loop (runs for exactly T_SIM seconds) ════════
            tic;
            while true
                t_elapsed = toc;
                if t_elapsed >= T_SIM, break; end
        
                %% ── Map real elapsed time → simulation index ──────────────────
                % t_elapsed goes 0 → T_SIM in real time
                % This directly indexes into the pre-computed simulation data
                k    = max(1, min(round(t_elapsed / dt) + 1, length(app.t_span)));
                spd  = app.y_out(k);
                dist = dist_cum(k);
        
                cx = 2 + (dist / REF_DIST) * (ROAD_LEN - 4);
                cx = min(cx, ROAD_LEN - 2);
        
                cla(ax);
                hold(ax,'on');
        
                %% ── STATIC ROAD ──────────────────────────────────────────────
                patch(ax,[0 ROAD_LEN ROAD_LEN 0],[-RH -RH RH RH],[0 0 0 0], ...
                      'FaceColor',[0.07 0.07 0.07],'EdgeColor','none');
                plot3(ax,[0 ROAD_LEN],[-RH -RH],[0.01 0.01],'w-','LineWidth',2.5);
                plot3(ax,[0 ROAD_LEN],[ RH  RH],[0.01 0.01],'w-','LineWidth',2.5);
        
                for xs = dashStarts
                    plot3(ax,[xs xs+4],[0 0],[0.01 0.01],'-', ...
                          'Color',[1 0.88 0.10],'LineWidth',1.5);
                end
        
                plot3(ax,[2 2],[-RH RH],[0.01 0.01],'w-','LineWidth',2.5);
                plot3(ax,[ROAD_LEN-2 ROAD_LEN-2],[-RH RH],[0.01 0.01],'w-','LineWidth',3);
        
               %% ── UNDERCARRIAGE (flat base plate) ──────────────────────
                patch(ax,[cx-BL, cx+BL, cx+BL, cx-BL], ...
                         [-BW,   -BW,    BW,    BW], ...
                         [BZ0 BZ0 BZ0 BZ0], ...
                         'FaceColor',undC,'EdgeColor','none');

                %% ── LOWER BODY (main slab) ───────────────────────────────
                Vb = [cx-BL, -BW, BZ0;  cx+BL, -BW, BZ0; ...
                      cx+BL,  BW, BZ0;  cx-BL,  BW, BZ0; ...
                      cx-BL, -BW, BZ1;  cx+BL, -BW, BZ1; ...
                      cx+BL,  BW, BZ1;  cx-BL,  BW, BZ1];
                patch(ax,'Vertices',Vb,'Faces',F6, ...
                      'FaceColor',bodyC,'EdgeColor','none');

                %% ── CABIN ────────────────────────────────────────────────
                xCf = cx + CBf;
                xCr = cx - CBr;
                Vc = [xCr,-CW,BZ1;  xCf,-CW,BZ1;  xCf, CW,BZ1;  xCr, CW,BZ1; ...
                      xCr,-CW,CZ1;  xCf,-CW,CZ1;  xCf, CW,CZ1;  xCr, CW,CZ1];
                patch(ax,'Vertices',Vc,'Faces',F6, ...
                      'FaceColor',cabC,'EdgeColor','none');

                %% ── WINDSHIELD (angled front glass) ──────────────────────
                patch(ax,'XData',[cx+BL,   cx+BL,   xCf,    xCf], ...
                         'YData',[-BW*0.80, BW*0.80, CW,    -CW], ...
                         'ZData',[BZ1,      BZ1,     CZ1,    CZ1], ...
                         'FaceColor',glassC,'FaceAlpha',0.80,'EdgeColor','none');

                %% ── REAR WINDOW (angled back glass) ──────────────────────
                patch(ax,'XData',[xCr,    xCr,   cx-BL,   cx-BL], ...
                         'YData',[-CW,     CW,    BW*0.80, -BW*0.80], ...
                         'ZData',[CZ1,     CZ1,   BZ1,      BZ1], ...
                         'FaceColor',glassC,'FaceAlpha',0.65,'EdgeColor','none');

                %% ── ROOF ─────────────────────────────────────────────────
                patch(ax,'XData',[xCr,  xCf,  xCf,  xCr], ...
                         'YData',[-CW,  -CW,   CW,   CW], ...
                         'ZData',[CZ1,  CZ1,  CZ1,  CZ1], ...
                         'FaceColor',cabC,'EdgeColor','none');

                %% ── HEADLIGHTS ───────────────────────────────────────────
                lZ1 = BZ0 + (BZ1-BZ0)*0.25;
                lZ2 = BZ0 + (BZ1-BZ0)*0.80;
                lXf = cx + BL + 0.01;
                % Left headlight
                patch(ax,'XData',repmat(lXf,1,4), ...
                         'YData',[-BW*0.85, -BW*0.18, -BW*0.18, -BW*0.85], ...
                         'ZData',[lZ1, lZ1, lZ2, lZ2], ...
                         'FaceColor',hlC,'EdgeColor','none');
                % Right headlight
                patch(ax,'XData',repmat(lXf,1,4), ...
                         'YData',[ BW*0.18,  BW*0.85,  BW*0.85,  BW*0.18], ...
                         'ZData',[lZ1, lZ1, lZ2, lZ2], ...
                         'FaceColor',hlC,'EdgeColor','none');

                %% ── TAILLIGHTS ───────────────────────────────────────────
                lXr = cx - BL - 0.01;
                patch(ax,'XData',repmat(lXr,1,4), ...
                         'YData',[-BW*0.85, -BW*0.18, -BW*0.18, -BW*0.85], ...
                         'ZData',[lZ1, lZ1, lZ2, lZ2], ...
                         'FaceColor',tlC,'EdgeColor','none');
                patch(ax,'XData',repmat(lXr,1,4), ...
                         'YData',[ BW*0.18,  BW*0.85,  BW*0.85,  BW*0.18], ...
                         'ZData',[lZ1, lZ1, lZ2, lZ2], ...
                         'FaceColor',tlC,'EdgeColor','none');

               %% ── WHEELS ───────────────────────────────────────────────
                for w = 1:4
                    wxc  = cx + WX_all(w);
                    wyc  = WY_all(w);
                    yOut = wyc + sign(wyc)*WHW;   % outer face
                    yIn  = wyc - sign(wyc)*WHW;   % inner face

                    % Outer tyre face
                    fill3(ax, wxc+WR*CT, yOut*ones(1,nth+1), WR+WR*ST, ...
                          tyrC,'EdgeColor',[0.28 0.28 0.28],'LineWidth',0.4);
                    % Rim
                    fill3(ax, wxc+WR*0.58*CT, yOut*ones(1,nth+1), WR+WR*0.58*ST, ...
                          rimC,'EdgeColor','none');
                    % Hub cap
                    fill3(ax, wxc+WR*0.18*CT, yOut*ones(1,nth+1), WR+WR*0.18*ST, ...
                          [0.45 0.45 0.48],'EdgeColor','none');
                    % Inner tyre face
                    fill3(ax, wxc+WR*CT, yIn*ones(1,nth+1), WR+WR*ST, ...
                          tyrC*0.6,'EdgeColor','none');
                end
        
                %% ── FIXED CAMERA ─────────────────────────────────────────────
                axis(ax,[0 ROAD_LEN -(RH+1.5) (RH+1.5) 0 3.0]);
                view(ax,10,35);
                ax.Color     = [0.04 0.04 0.04];
                ax.GridColor = [0.20 0.20 0.20];
                grid(ax,'off');
                ax.XTickLabel=''; ax.YTickLabel=''; ax.ZTickLabel='';
                xlabel(ax,''); ylabel(ax,''); zlabel(ax,'');
                title(ax, sprintf('Speed: %.1f m/s  |  Dist: %.0f m  |  t = %.1fs / %.0fs', ...
                      spd, dist, t_elapsed, T_SIM), ...
                      'Color',[0.85 0.85 0.85],'FontSize',12);
        
                hold(ax,'off');
                updateResponsePlot(app, k);
                app.Gauge.Value  = spd;
                app.Slider.Value = app.t_span(k);
                drawnow limitrate;
            end
        
            %% ── Snap to final state ──────────────────────────────────────────────
            app.Gauge.Value  = app.y_out(end);
            app.Slider.Value = app.t_span(end);
            updateResponsePlot(app);
        end
    
     
        function updateMetrics(app)
            y = app.y_out;
            t = app.t_span;
            r_final = app.r_out(end);
            n = length(y);
        
            % Rise time: 10% to 90% of final value
            riseTime = 0;
            for i = 1:n
                if y(i) >= 0.9 * r_final
                    riseTime = t(i);
                    break;
                end
            end
        
            % Settling time: last time output leaves 2% band
            settlingTime = t(end);
            for i = n:-1:1
                if abs(y(i) - r_final) > 0.02 * r_final
                    settlingTime = t(min(i+1, n));
                    break;
                end
            end
        
            % Overshoot
            peak = max(y);
            overshoot = 0;
            if r_final > 0
                overshoot = max(0, (peak - r_final) / r_final * 100);
            end
        
            % Steady-state error
            sse = abs(r_final - y(end));
        
            % Display
            app.RiseTimeField.Value     = round(riseTime, 3);
            app.SettlingTimeField.Value = round(settlingTime, 3);
            app.OvershootField.Value    = round(overshoot, 2);
            app.SSEField.Value          = round(sse, 4);
            % Colour code the overshoot field
            if overshoot > 15
                app.OvershootField.FontColor = [0.8 0.1 0.1];  % red = bad
            elseif overshoot > 12
                app.OvershootField.FontColor = [0.8 0.5 0.0];  % orange = warning
            else
                app.OvershootField.FontColor = [0.1 0.6 0.2];  % green = good
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
              % Set slider values FIRST
            app.VrefKnob.Value  = 15;
            app.kpSlider.Value  = 1.17;
            app.KiSlider.Value  = 0.57;
            app.kdSlider.Value  = 0.27;
        
            % THEN sync edit fields from sliders
            app.KpEditField.Value = app.kpSlider.Value;
            app.KiEditField.Value = app.KiSlider.Value;
            app.dEditField.Value = app.kdSlider.Value;
            app.Gauge.Value = 0;
            app.Slider.Value = 0;
            app.Lamp.Color = [1 0 0];  % red at start
            % Auto-run on open
            runSimulation(app);
        end

        % Value changed function: VrefKnob
        function VrefKnobValueChanged3(app, event)
           app.Label.Text = sprintf('%.1f m/s', app.VrefKnob.Value);
           runSimulation(app);

        end

        % Value changed function: StartButton
        function StartButtonValueChanged(app, event)
            if app.StartButton.Value
                app.Lamp.Color = [0 1 0];  % green when running
                app.StartButton.Text = 'Stop';
                app.isRunning = true;
                runSimulation(app);
            else
                app.Lamp.Color = [1 0 0];  % red at start
                app.StartButton.Text = 'Start';
                app.isRunning = false;
            end                    
        end

        % Value changed function: KiSlider
        function KiSliderValueChanged(app, event)
            app.KiEditField.Value = app.KiSlider.Value;
    runSimulation(app);
        end

        % Value changed function: kdSlider
        function kdSliderValueChanged(app, event)
             app.dEditField.Value = app.kdSlider.Value;
    runSimulation(app);
            
        end

        % Value changed function: kpSlider
        function kpSliderValueChanged(app, event)
           app.KpEditField.Value = app.kpSlider.Value;
            runSimulation(app);
            
        end

        % Value changed function: KpEditField
        function KpEditFieldValueChanged(app, event)
            val = app.KpEditField.Value;
            % Clamp to slider limits
            val = max(0, min(10, val));
            app.KpEditField.Value = val;
            app.kpSlider.Value = val;
            runSimulation(app);
            
        end

        % Value changed function: KiEditField
        function KiEditFieldValueChanged(app, event)
            val = app.KiEditField.Value;
            val = max(0, min(5, val));
            app.KiEditField.Value = val;
            app.KiSlider.Value = val;
            runSimulation(app);
            
        end

        % Value changed function: dEditField
        function dEditFieldValueChanged(app, event)
            val = app.dEditField.Value;
            val = max(0, min(2, val));
            app.dEditField.Value = val;
            app.kdSlider.Value = val;
            runSimulation(app);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StartButton = uibutton(app.UIFigure, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged, true);
            app.StartButton.Text = 'START';
            app.Panel = uipanel(app.UIFigure);
            app.RiseTimeEditFieldLabel = uilabel(app.Panel);
            app.RiseTimeEditFieldLabel.Text = 'Rise Time';
            app.RiseTimeField = uieditfield(app.Panel, 'numeric');
            app.SettlingTimeEditFieldLabel = uilabel(app.Panel);
            app.SettlingTimeEditFieldLabel.Text = 'Settling Time';
            app.SettlingTimeField = uieditfield(app.Panel, 'numeric');
            app.OvershootEditFieldLabel = uilabel(app.Panel);
            app.OvershootEditFieldLabel.Text = 'Overshoot %';
            app.OvershootField = uieditfield(app.Panel, 'numeric');
            app.SSEEditFieldLabel = uilabel(app.Panel);
            app.SSEEditFieldLabel.Text = 'SSE';
            app.SSEField = uieditfield(app.Panel, 'numeric');
            app.Panel_2 = uipanel(app.UIFigure);
            app.KiSlider = uislider(app.Panel_2);
            app.KiSlider.Limits = [0 5];
            app.KiSlider.MajorTicks = [0 5];
            app.KiSlider.MajorTickLabels = {'0', '5'};
            app.KiSlider.ValueChangedFcn = createCallbackFcn(app, @KiSliderValueChanged, true);
            app.kdSlider = uislider(app.Panel_2);
            app.kdSlider.Limits = [0 2];
            app.kdSlider.MajorTicks = [0 2];
            app.kdSlider.MajorTickLabels = {'0', '2'};
            app.kdSlider.ValueChangedFcn = createCallbackFcn(app, @kdSliderValueChanged, true);
            app.kpSlider = uislider(app.Panel_2);
            app.kpSlider.Limits = [0 10];
            app.kpSlider.MajorTicks = [0 10];
            app.kpSlider.MajorTickLabels = {'0', '10'};
            app.kpSlider.ValueChangedFcn = createCallbackFcn(app, @kpSliderValueChanged, true);
            app.KpEditField = uieditfield(app.Panel_2, 'numeric');
            app.KpEditField.ValueChangedFcn = createCallbackFcn(app, @KpEditFieldValueChanged, true);
            app.KiEditField = uieditfield(app.Panel_2, 'numeric');
            app.KiEditField.ValueChangedFcn = createCallbackFcn(app, @KiEditFieldValueChanged, true);
            app.dEditField = uieditfield(app.Panel_2, 'numeric');
            app.dEditField.ValueChangedFcn = createCallbackFcn(app, @dEditFieldValueChanged, true);
            app.Panel_8 = uipanel(app.Panel_2);
            app.PIDCONTROLLERGAINSLabel = uilabel(app.Panel_8);
            app.PIDCONTROLLERGAINSLabel.Text = 'PID CONTROLLER GAINS';
            app.KpLabel = uilabel(app.Panel_2);
            app.KpLabel.Text = 'Kp';
            app.KiLabel = uilabel(app.Panel_2);
            app.KiLabel.Text = 'Ki';
            app.KdLabel = uilabel(app.Panel_2);
            app.KdLabel.Text = 'Kd';
            app.Panel_4 = uipanel(app.UIFigure);
            app.VehicleAxes = uiaxes(app.Panel_4);
            title(app.VehicleAxes, 'Vehicle Motion')
            xlabel(app.VehicleAxes, 'X')
            ylabel(app.VehicleAxes, 'Y')
            zlabel(app.VehicleAxes, 'Z')
            app.VehicleAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.VEHICLEANIMATIONLabel = uilabel(app.Panel_4);
            app.VEHICLEANIMATIONLabel.Text = 'VEHICLE ANIMATION';
            app.Panel_5 = uipanel(app.UIFigure);
            app.SpeedAxes = uiaxes(app.Panel_5);
            title(app.SpeedAxes, 'Speed Response')
            xlabel(app.SpeedAxes, 'Time')
            ylabel(app.SpeedAxes, 'Speed')
            zlabel(app.SpeedAxes, 'Z')
            app.Panel_7 = uipanel(app.UIFigure);
            app.AUTONOMOUSVEHICLESPEEDCONTROLLERLabel = uilabel(app.Panel_7);
            app.AUTONOMOUSVEHICLESPEEDCONTROLLERLabel.Text = 'AUTONOMOUS VEHICLE SPEED CONTROLLER';
            app.StatusLampLabel = uilabel(app.Panel_7);
            app.StatusLampLabel.Text = 'Status';
            app.Lamp = uilamp(app.Panel_7);
            app.Panel_9 = uipanel(app.UIFigure);
            app.Label = uilabel(app.Panel_9);
            app.Label.Text = '';
            app.REFERENCESPEEDLabel = uilabel(app.Panel_9);
            app.REFERENCESPEEDLabel.Text = 'REFERENCE SPEED';
            app.VrefKnob = uiknob(app.Panel_9, 'continuous');
            app.VrefKnob.Limits = [10 30];
            app.VrefKnob.MajorTicks = [0 5 10 15 20 25 30];
            app.VrefKnob.ValueChangedFcn = createCallbackFcn(app, @VrefKnobValueChanged3, true);
            app.VrefKnob.Value = 10;
            app.Panel_10 = uipanel(app.UIFigure);
            app.SIMULATIONTIMELabel = uilabel(app.Panel_10);
            app.SIMULATIONTIMELabel.Text = 'SIMULATION TIME';
            app.Slider = uislider(app.Panel_10);
            app.Slider.Limits = [0 10];
            app.Slider.MajorTicks = [0 10];
            app.Slider.Step = 0.0001;
            app.Slider.Enable = 'off';
            app.Panel_11 = uipanel(app.UIFigure);
            app.Gauge = uigauge(app.Panel_11, 'semicircular');
            app.Gauge.Limits = [0 30];
            app.SPEEDOFTHECONTROLLEDCARLabel = uilabel(app.Panel_11);
            app.SPEEDOFTHECONTROLLEDCARLabel.Text = 'SPEED OF THE CONTROLLED CAR';
            app.COMMENTDESCRIPTIONSPanel = uipanel(app.UIFigure);
            app.COMMENTDESCRIPTIONSPanel.Title = 'COMMENT / DESCRIPTIONS';
            app.TextArea = uitextarea(app.COMMENTDESCRIPTIONSPanel);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'PLANT MODEL'; 'G(s) = 5/(2s+1) · e^(-0.3s)'; 'K=5.0 | τ=2.0s | Td=0.3s'; ''; 'CONTROLLER'; 'PID — Ziegler-Nichols Tuned'; 'C(s) = Kp + Ki/s + Kd·s'; ''; 'TUNED GAINS'; 'Kp=1.17 | Ki=0.57 | Kd=0.27'; ''; 'PERFORMANCE TARGETS'; 'Rise Time   < 2.0 s'; 'Settling    < 5.0 s  '; 'Overshoot   < 20 %'; 'SSE         ≈ 0'; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; 'GROUP 1 | MCE 532 | FUT Minna'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = speed1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end